classdef appDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TextArea_2                   matlab.ui.control.TextArea
        TextArea                     matlab.ui.control.TextArea
        ProcessButton                matlab.ui.control.Button
        CardinalMarksdetectorLabel   matlab.ui.control.Label
        North90EastLabel             matlab.ui.control.Label
        YourDirectionEditField       matlab.ui.control.NumericEditField
        YourDirectionEditFieldLabel  matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        SelectImageButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        selectedImagePath = ""; % path of the image
        dangerDirection;
        safeDirection;
        userDirection = 0;
    end
    
    methods (Access = private)
        
        function showCardArrow(app)

            arrowdirection = mod((app.dangerDirection + 180) - app.userDirection - 90, 360) ; 
            arrowdirection = deg2rad(arrowdirection); 
            
            img = imread(app.selectedImagePath);
            imshow(img, 'Parent', app.UIAxes); 
            
           
            text(15, 15, app.safeDirection, 'FontSize', 12, 'Color', 'black', 'FontWeight', 'bold', 'Parent', app.UIAxes);
            
            startX = 50;  
            startY = 50; 
            
            % Calcul des coordonnées de la flèche
            arrowLength = 30;  
            deltaX = arrowLength * cos(arrowdirection); 
            deltaY = arrowLength * sin(arrowdirection);  

            hold(app.UIAxes,"on");
            quiver(startX, startY, deltaX, deltaY, 0, 'Parent', app.UIAxes ...
                , 'Color', 'red', 'LineWidth', 2);
            hold(app.UIAxes,"off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
           % Ouvrir la boîte de dialogue pour sélectionner une image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Images (*.jpg, *.png, *.bmp)'; '*.*', 'Tous les fichiers'}, ...
                                      'Sélectionnez une image');
            
            % Vérifier si l'utilisateur a annulé la sélection
            if isequal(file, 0)
                disp('Aucune image sélectionnée.');
            else
                % Construire le chemin complet de l'image
                app.selectedImagePath = fullfile(path, file);

                % Charger et afficher l'image
                img = imread(app.selectedImagePath); % Lire l'image
                imshow(img, 'Parent', app.UIAxes); % Afficher l'image dans le champ UIAxes
                title(file, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if not(app.selectedImagePath == "")
                img = imread(app.selectedImagePath);
                [result,consistency]=doubleEstimation(img);
                app.TextArea.Value = [result  newline "Consistency : " + consistency];

                if consistency
                    if result == "East"
                        app.dangerDirection = 90;
                        app.safeDirection = "West";
                    elseif result == "North"
                        app.dangerDirection = 0;
                        app.safeDirection = "South";
                    elseif result == "South"
                        app.dangerDirection = 180;
                        app.safeDirection = "North";
                    else 
                        app.dangerDirection = 270;
                        app.safeDirection = "East";
                    end

                    showCardArrow(app);
                end
            end
        end

        % Value changed function: YourDirectionEditField
        function YourDirectionEditFieldValueChanged(app, event)
            app.userDirection = app.YourDirectionEditField.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.YourDirectionEditFieldLabel = uilabel(app.LeftPanel);
            app.YourDirectionEditFieldLabel.Text = 'Your Direction';
            app.YourDirectionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YourDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @YourDirectionEditFieldValueChanged, true);
            app.North90EastLabel = uilabel(app.LeftPanel);
            app.North90EastLabel.Text = '0° = North; 90° = East';
            app.CardinalMarksdetectorLabel = uilabel(app.LeftPanel);
            app.CardinalMarksdetectorLabel.Text = {'Cardinal Marks '; 'detector'};
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Tag = 'resultTextArea';
            app.TextArea_2 = uitextarea(app.LeftPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'If the consitency is ''true'' then both the algorithms got the same results else both the results are visible and the first is the triangleMethod result and the second is the colorMethod result. '; 'The arrow for the safe direction appears only when the consistency is true.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.SelectImageButton = uibutton(app.RightPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end